classdef Prova_Immagini_gui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        StartButton        matlab.ui.control.Button
        OptionButtonGroup  matlab.ui.container.ButtonGroup
        GaussianoButton    matlab.ui.control.RadioButton
        NonuniformeButton  matlab.ui.control.RadioButton
        FiltromediaButton  matlab.ui.control.RadioButton
        SelectimageButton  matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageFile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            % Display uigetfile dialog
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
              % Display uigetfile dialog
            app.UIAxes2.Visible = 'off';
            app.UIAxes2.Colormap = gray(256);
            axis(app.UIAxes2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           % prova=imread('Baboon.bmp');
            app.ImageFile=imread(fname) ;
            imagesc(app.UIAxes,app.ImageFile);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.FiltromediaButton.Value==true
                %prova=imread('Baboon.bmp');
                dim_filter = 6; % [dim_filter x dim_filter] average filter
                H_average = fspecial('average', dim_filter); 
                % apply the average filter to the image
                I_average = imfilter(app.ImageFile, H_average);                
                I_average=uint8(I_average);
                imagesc(app.UIAxes2,I_average);
            end 
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Enanched')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.Title = 'Option';
            app.FiltromediaButton = uiradiobutton(app.OptionButtonGroup);
            app.FiltromediaButton.Text = 'Filtro media';
            app.FiltromediaButton.Value = true;
            app.NonuniformeButton = uiradiobutton(app.OptionButtonGroup);
            app.NonuniformeButton.Text = 'Non uniforme';
            app.GaussianoButton = uiradiobutton(app.OptionButtonGroup);
            app.GaussianoButton.Text = 'Gaussiano';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prova_Immagini_gui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end