classdef gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        filtrineltempoTab               matlab.ui.container.Tab
        selezionaimmagineButton         matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        filtromediaTab                  matlab.ui.container.Tab
        grandezzamatriceSpinner_2       matlab.ui.control.Spinner
        grandezzamatriceSpinner_2Label  matlab.ui.control.Label
        filtrogaussianoTab              matlab.ui.container.Tab
        grandezzamatriceSpinner         matlab.ui.control.Spinner
        grandezzamatriceSpinnerLabel    matlab.ui.control.Label
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        filtromedianoTab                matlab.ui.container.Tab
        grandezzamatriceSpinner_3       matlab.ui.control.Spinner
        grandezzamatriceSpinner_3Label  matlab.ui.control.Label
        filtripassaaltoTab              matlab.ui.container.Tab
        applicafiltroButton             matlab.ui.control.Button
        enhancementdellimmagineButton   matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CannyButton                     matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        LaplacianoButton                matlab.ui.control.RadioButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        filtriinfrequenzaTab            matlab.ui.container.Tab
        selezionaimmagineButton_2       matlab.ui.control.Button
        TabGroup2_2                     matlab.ui.container.TabGroup
        idealelpTab                     matlab.ui.container.Tab
        RaggioSpinner                   matlab.ui.control.Spinner
        RaggioSpinnerLabel              matlab.ui.control.Label
        gaussianolpTab                  matlab.ui.container.Tab
        sigmaSlider_2                   matlab.ui.control.Slider
        sigmaSlider_2Label              matlab.ui.control.Label
        butterworthlpTab                matlab.ui.container.Tab
        sigmaSlider_3                   matlab.ui.control.Slider
        sigmaSlider_3Label              matlab.ui.control.Label
        ordineSpinner                   matlab.ui.control.Spinner
        ordineSpinnerLabel              matlab.ui.control.Label
        idealehpTab                     matlab.ui.container.Tab
        RaggioSpinner_2                 matlab.ui.control.Spinner
        RaggioSpinner_2Label            matlab.ui.control.Label
        gaussianohpTab                  matlab.ui.container.Tab
        sigmaSlider_4                   matlab.ui.control.Slider
        sigmaSlider_4Label              matlab.ui.control.Label
        butterworthhpTab                matlab.ui.container.Tab
        sigmaSlider_5                   matlab.ui.control.Slider
        sigmaSlider_5Label              matlab.ui.control.Label
        ordineSpinner_2                 matlab.ui.control.Spinner
        ordineSpinner_2Label            matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        trasformatawaveletTab           matlab.ui.container.Tab
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        imInput % immagine in input
        imOutput % ummagine in output
        grandezzaMatrice = 3 %inizializzo un valore di default per evitare che vada in errore
        sigma = 3 %inizializzo un valore di default per evitare che vada in errore
        currentFilter = "laplaciano" %serve per tenere traccia del filtro passa alto corrente 
        raggio= 35
        sigmag=3
        sigmab=3
        ordine=1
    end
    
    methods (Access = private)
        
        function y =  distmatrix(app,M,N)
            
                    
                    u = 0:(M - 1);
                    v = 0:(N - 1);
                    
                    ind_u = find(u > M/2);
                    u(ind_u) = u(ind_u) - M;
                    ind_v = find(v > N/2);
                    v(ind_v) = v(ind_v) - N;
                    
                    [V, U] = meshgrid(v, u);
                    
                    %calculate distance matrix
                    y = sqrt((U .^ 2) + (V .^ 2));
        end
    
        
        function [res] = filtro_ideale(app,I,dist)
            I(I <= dist) = 1;
            I(I > dist) = 0;
            res = I;
        end
        
        function [res] = filtro_gaussiano_frequenza(app,imageA,sigma)
            [hA,  lA] = size(imageA);
            dist = distmatrix(app,hA,lA);
            res = exp(-(dist.^2)/(2*sigma^2));
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selezionaimmagineButton
        function selezionaimmagineButtonPushed(app, event)
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image');
            % Display uigetfile dialog
            app.UIAxes2.Visible = 'off';
            app.UIAxes2.Colormap = gray(256);
            axis(app.UIAxes2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes,app.imInput);
        end

        % Callback function
        function filtroGaussianoLPSButtonPushed(app, event)
                    dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
                    gauss_variance = app.sigma; % variance
                        H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
                    I_gaussian = imfilter(app.imInput, H_gaussian);
                    imagesc(app.UIAxes2,I_gaussian);
        end

        % Callback function
        function grandezzamatriceEditFieldValueChanged(app, event)
            value = app.grandezzamatriceEditField.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: sigmaSlider
        function sigmaSliderValueChanging(app, event)
            changingValue = event.Value;
            dim_gauss_filter = [app.grandezzaMatrice app.grandezzaMatrice]; % dim of filter
            gauss_variance = changingValue; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            I_gaussian = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,I_gaussian);
        end

        % Value changed function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanged(app, event)
            value = app.grandezzamatriceSpinner.Value;
            app.grandezzaMatrice = value;
        end

        % Value changing function: grandezzamatriceSpinner
        function grandezzamatriceSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            dim_gauss_filter = [app.grandezzaMatrice  app.grandezzaMatrice ]; % dim of filter
            gauss_variance = app.sigma; % variance
            H_gaussian = fspecial("gaussian", dim_gauss_filter, gauss_variance);
            I_gaussian = imfilter(app.imInput, H_gaussian);
            imagesc(app.UIAxes2,I_gaussian);
        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            value = app.sigmaSlider.Value;
            app.sigma = value;
        end

        % Value changing function: grandezzamatriceSpinner_2
        function grandezzamatriceSpinner_MediaValueChanging(app, event)
            changingValue = event.Value;
           H_average = fspecial('average', changingValue); 
                % apply the average filter to the image
                I_average = imfilter(app.imInput, H_average);                
                I_average=uint8(I_average);
                imagesc(app.UIAxes2,I_average);
        end

        % Value changing function: grandezzamatriceSpinner_3
        function grandezzamatriceSpinner_3ValueChanging(app, event)
            changingValue = event.Value;
            app.grandezzaMatrice = changingValue;
            I_denoised = medfilt2(app.imInput, [app.grandezzaMatrice app.grandezzaMatrice]);
            imagesc(app.UIAxes2,I_denoised);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChangedHPS(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Laplaciano'
                    app.currentFilter = "laplaciano";
                case 'Prewitt'
                    app.currentFilter = "prewitt";
                case 'Sobel'
                    app.currentFilter = "sobel"; 
                case 'Canny'
                    app.currentFilter = "canny";
            end
        end

        % Button pushed function: enhancementdellimmagineButton
        function enhancedImmagineButtonPushedHPS(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    filtrata = imfilter(double_input,filter);
                    finale = uint8(double_input - filtrata);
                    imagesc(app.UIAxes2,finale);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    final = uint8(double_input + filtered);
                    imagesc(app.UIAxes2,final);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    final = uint8(filtered + double_input);
                    imagesc(app.UIAxes2,final);
                case "canny"
                    filtered = edge(double_input,'canny');
                    final = uint8(filtered );
                    imagesc(app.UIAxes2,final);
            end
        end

        % Button pushed function: applicafiltroButton
        function applicafiltroButtonPushed(app, event)
            double_input = double(app.imInput);
           
            switch app.currentFilter
                case "laplaciano"
                    filter = fspecial("laplacian");
                    filtrata = uint8(imfilter(double_input,filter));
                    imagesc(app.UIAxes2,filtrata);
                case "prewitt"
                    [filtered, ~ , ~]=prewitt(double_input);
                    final = uint8(filtered);
                    imagesc(app.UIAxes2,final);
                case "sobel"
                    [filtered, ~ , ~]=sobel(double_input);
                    final = uint8(filtered);
                    imagesc(app.UIAxes2,final);
                case "canny"
                    final = uint8(edge(double_input,'canny'));
                    imagesc(app.UIAxes2,final);
            end
        end

        % Button pushed function: selezionaimmagineButton_2
        function selezionaimmagineButton_2Pushed(app, event)
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_2.Colormap = gray(256);
            axis(app.UIAxes_2, 'image');
            % Display uigetfile dialog
            app.UIAxes2_2.Visible = 'off';
            app.UIAxes2_2.Colormap = gray(256);
            axis(app.UIAxes2_2, 'image');
            
            filterspec = {'*.jpg;*.tif;*.png;*.bmp;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            fname=[p f];
           
            app.imInput=imread(fname) ;
            imagesc(app.UIAxes_2,app.imInput);
        end

        % Value changed function: RaggioSpinner
        function RaggioSpinnerValueChanged(app, event)
            value = app.RaggioSpinner.Value;
            app.raggio=value;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.imInput);
                matrice= app.distmatrix(hA,lA);
                mask = app.filtro_ideale(matrice,app.raggio);
                %applico il filtro shiftato
                fttA_filtered = fftshift(mask) .* fttAS;
                %anti shifto e trasformo l'immagine
                imOutput = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,imOutput);
        end

        % Value changed function: sigmaSlider_2
        function sigmaSlider_2ValueChanged(app, event)
            value = app.sigmaSlider_2.Value;
            app.sigmag=value;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                gaussiano = app.filtro_gaussiano_frequenza(app.imInput,app.sigmag);
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,imageB);

        end

        % Value changed function: sigmaSlider_3
        function sigmaSlider_3ValueChanged(app, event)
            value = app.sigmaSlider_3.Value;
            app.sigmab=value;
          
        end

        % Value changing function: ordineSpinner
        function ordineSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.ordine=changingValue;  
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.imInput);
                dist = app.distmatrix(hA,lA);
                butterworth = 1./(1+(dist./app.sigmab).^(2*app.ordine));
                %applico il filtro shiftato
                fttA_filtered = fftshift(butterworth) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,imageB);
        end

        % Value changed function: RaggioSpinner_2
        function RaggioSpinner_2ValueChanged(app, event)
            value = app.RaggioSpinner_2.Value;
            app.raggio=value;
             %faccio la trasformata
                    fftA = fft2(app.imInput);
                    % shifto l'immagine 
                    fttAS = fftshift(fftA);
                    % calcolo la maschera
                    [hA,  lA] = size(app.imInput);
                    distmatrix = app.distmatrix(hA,lA);
                    mask = 1-(app.filtro_ideale(distmatrix,app.raggio));
                    %applico il filtro shiftato
                    fttA_filtered = fftshift(mask) .* fttAS;
                    %anti shifto e trasformo l'immagine
                    imageB = ifft2(fftshift(fttA_filtered));
                    imagesc(app.UIAxes2_2,imageB);
        end

        % Value changed function: sigmaSlider_4
        function sigmaSlider_4ValueChanged(app, event)
            value = app.sigmaSlider_4.Value;
            app.sigma=value;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                [hA,  lA] = size(app.imInput);
                dist = app.distmatrix(hA,lA);
                gaussiano = 1-(exp(-(dist.^2)/(2*app.sigma^2)));
                %applico il filtro shiftato
                fttA_filtered = fftshift(gaussiano) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,imageB);
        end

        % Value changed function: sigmaSlider_5
        function sigmaSlider_5ValueChanged(app, event)
            value = app.sigmaSlider_5.Value;
            app.sigma=value;
        end

        % Value changing function: ordineSpinner_2
        function ordineSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.ordine=changingValue;
            %faccio la trasformata
                fftA = fft2(app.imInput);
                % shifto l'immagine 
                fttAS = fftshift(fftA);
                % calcolo la maschera
                
                [hA,  lA] = size(app.imInput);
                dist = app.distmatrix(hA,lA);
                butterworth = 1-(1./(1+(dist./app.sigma).^(2*app.ordine)));
                %applico il filtro shiftato
                fttA_filtered = fftshift(butterworth) .* fttAS;
                %anti shifto e trasformo l'immagine
                imageB = ifft2(fftshift(fttA_filtered));
                imagesc(app.UIAxes2_2,imageB);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.filtrineltempoTab = uitab(app.TabGroup);
            app.filtrineltempoTab.Title = 'filtri nel tempo';
            app.UIAxes = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes, 'INPUT')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.filtrineltempoTab);
            title(app.UIAxes2, 'OUTPUT')
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup2 = uitabgroup(app.filtrineltempoTab);
            app.filtromediaTab = uitab(app.TabGroup2);
            app.filtromediaTab.Title = 'filtro media ';
            app.grandezzamatriceSpinner_2Label = uilabel(app.filtromediaTab);
            app.grandezzamatriceSpinner_2Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_2 = uispinner(app.filtromediaTab);
            app.grandezzamatriceSpinner_2.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_MediaValueChanging, true);
            app.grandezzamatriceSpinner_2.Limits = [1 256];
            app.grandezzamatriceSpinner_2.Value = 3;
            app.filtrogaussianoTab = uitab(app.TabGroup2);
            app.filtrogaussianoTab.Title = 'filtro gaussiano';
            app.sigmaSliderLabel = uilabel(app.filtrogaussianoTab);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.filtrogaussianoTab);
            app.sigmaSlider.Limits = [1 50];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.ValueChangingFcn = createCallbackFcn(app, @sigmaSliderValueChanging, true);
            app.sigmaSlider.Value = 5;
            app.grandezzamatriceSpinnerLabel = uilabel(app.filtrogaussianoTab);
            app.grandezzamatriceSpinnerLabel.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner = uispinner(app.filtrogaussianoTab);
            app.grandezzamatriceSpinner.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanging, true);
            app.grandezzamatriceSpinner.Limits = [1 256];
            app.grandezzamatriceSpinner.ValueChangedFcn = createCallbackFcn(app, @grandezzamatriceSpinnerValueChanged, true);
            app.grandezzamatriceSpinner.Value = 10;
            app.filtromedianoTab = uitab(app.TabGroup2);
            app.filtromedianoTab.Title = 'filtro mediano';
            app.grandezzamatriceSpinner_3Label = uilabel(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3Label.Text = 'grandezza matrice';
            app.grandezzamatriceSpinner_3 = uispinner(app.filtromedianoTab);
            app.grandezzamatriceSpinner_3.ValueChangingFcn = createCallbackFcn(app, @grandezzamatriceSpinner_3ValueChanging, true);
            app.grandezzamatriceSpinner_3.Limits = [2 256];
            app.grandezzamatriceSpinner_3.Value = 3;
            app.filtripassaaltoTab = uitab(app.TabGroup2);
            app.filtripassaaltoTab.Title = 'filtri passa alto ';
            app.ButtonGroup = uibuttongroup(app.filtripassaaltoTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChangedHPS, true);
            app.ButtonGroup.Title = 'Button Group';
            app.LaplacianoButton = uiradiobutton(app.ButtonGroup);
            app.LaplacianoButton.Text = 'Laplaciano';
            app.LaplacianoButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.ButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.CannyButton = uiradiobutton(app.ButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.enhancementdellimmagineButton = uibutton(app.filtripassaaltoTab, 'push');
            app.enhancementdellimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @enhancedImmagineButtonPushedHPS, true);
            app.enhancementdellimmagineButton.Text = 'enhancement dell''immagine';
            app.applicafiltroButton = uibutton(app.filtripassaaltoTab, 'push');
            app.applicafiltroButton.ButtonPushedFcn = createCallbackFcn(app, @applicafiltroButtonPushed, true);
            app.applicafiltroButton.Text = 'applica filtro';
            app.selezionaimmagineButton = uibutton(app.filtrineltempoTab, 'push');
            app.selezionaimmagineButton.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButtonPushed, true);
            app.selezionaimmagineButton.Text = 'seleziona immagine';
            app.filtriinfrequenzaTab = uitab(app.TabGroup);
            app.filtriinfrequenzaTab.Title = 'filtri in frequenza';
            app.UIAxes_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes_2, 'INPUT')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ZTick = [];
            app.UIAxes2_2 = uiaxes(app.filtriinfrequenzaTab);
            title(app.UIAxes2_2, 'OUTPUT')
            app.UIAxes2_2.XLim = [0 1];
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.TabGroup2_2 = uitabgroup(app.filtriinfrequenzaTab);
            app.idealelpTab = uitab(app.TabGroup2_2);
            app.idealelpTab.Title = 'ideale lp';
            app.RaggioSpinnerLabel = uilabel(app.idealelpTab);
            app.RaggioSpinnerLabel.Text = 'Raggio';
            app.RaggioSpinner = uispinner(app.idealelpTab);
            app.RaggioSpinner.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinnerValueChanged, true);
            app.RaggioSpinner.Value = 35;
            app.gaussianolpTab = uitab(app.TabGroup2_2);
            app.gaussianolpTab.Title = 'gaussiano lp';
            app.sigmaSlider_2Label = uilabel(app.gaussianolpTab);
            app.sigmaSlider_2Label.Text = 'sigma';
            app.sigmaSlider_2 = uislider(app.gaussianolpTab);
            app.sigmaSlider_2.Limits = [1 50];
            app.sigmaSlider_2.ValueChangedFcn = createCallbackFcn(app, @sigmaSlider_2ValueChanged, true);
            app.sigmaSlider_2.Value = 5;
            app.butterworthlpTab = uitab(app.TabGroup2_2);
            app.butterworthlpTab.Title = 'butterworth lp';
            app.ordineSpinnerLabel = uilabel(app.butterworthlpTab);
            app.ordineSpinnerLabel.Text = 'ordine';
            app.ordineSpinner = uispinner(app.butterworthlpTab);
            app.ordineSpinner.ValueChangingFcn = createCallbackFcn(app, @ordineSpinnerValueChanging, true);
            app.sigmaSlider_3Label = uilabel(app.butterworthlpTab);
            app.sigmaSlider_3Label.Text = 'sigma';
            app.sigmaSlider_3 = uislider(app.butterworthlpTab);
            app.sigmaSlider_3.Limits = [0 50];
            app.sigmaSlider_3.ValueChangedFcn = createCallbackFcn(app, @sigmaSlider_3ValueChanged, true);
            app.idealehpTab = uitab(app.TabGroup2_2);
            app.idealehpTab.Title = 'ideale hp';
            app.RaggioSpinner_2Label = uilabel(app.idealehpTab);
            app.RaggioSpinner_2Label.Text = 'Raggio';
            app.RaggioSpinner_2 = uispinner(app.idealehpTab);
            app.RaggioSpinner_2.ValueChangedFcn = createCallbackFcn(app, @RaggioSpinner_2ValueChanged, true);
            app.RaggioSpinner_2.Value = 35;
            app.gaussianohpTab = uitab(app.TabGroup2_2);
            app.gaussianohpTab.Title = 'gaussiano hp';
            app.sigmaSlider_4Label = uilabel(app.gaussianohpTab);
            app.sigmaSlider_4Label.Text = 'sigma';
            app.sigmaSlider_4 = uislider(app.gaussianohpTab);
            app.sigmaSlider_4.Limits = [1 50];
            app.sigmaSlider_4.ValueChangedFcn = createCallbackFcn(app, @sigmaSlider_4ValueChanged, true);
            app.sigmaSlider_4.Value = 5;
            app.butterworthhpTab = uitab(app.TabGroup2_2);
            app.butterworthhpTab.Title = 'butterworth hp';
            app.ordineSpinner_2Label = uilabel(app.butterworthhpTab);
            app.ordineSpinner_2Label.Text = 'ordine';
            app.ordineSpinner_2 = uispinner(app.butterworthhpTab);
            app.ordineSpinner_2.ValueChangingFcn = createCallbackFcn(app, @ordineSpinner_2ValueChanging, true);
            app.sigmaSlider_5Label = uilabel(app.butterworthhpTab);
            app.sigmaSlider_5Label.Text = 'sigma';
            app.sigmaSlider_5 = uislider(app.butterworthhpTab);
            app.sigmaSlider_5.Limits = [0 50];
            app.sigmaSlider_5.ValueChangedFcn = createCallbackFcn(app, @sigmaSlider_5ValueChanged, true);
            app.selezionaimmagineButton_2 = uibutton(app.filtriinfrequenzaTab, 'push');
            app.selezionaimmagineButton_2.ButtonPushedFcn = createCallbackFcn(app, @selezionaimmagineButton_2Pushed, true);
            app.selezionaimmagineButton_2.Text = 'seleziona immagine';
            app.trasformatawaveletTab = uitab(app.TabGroup);
            app.trasformatawaveletTab.Title = 'trasformata wavelet';
            app.UIAxes_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes_3, 'INPUT')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ZTick = [];
            app.UIAxes2_3 = uiaxes(app.trasformatawaveletTab);
            title(app.UIAxes2_3, 'OUTPUT')
            app.UIAxes2_3.XLim = [0 1];
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end